classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PersonalizarRedeTab            matlab.ui.container.Tab
        TesteEditField                 matlab.ui.control.NumericEditField
        TesteEditFieldLabel            matlab.ui.control.Label
        ValidacaoEditField             matlab.ui.control.NumericEditField
        ValidaoEditFieldLabel          matlab.ui.control.Label
        TreinoEditField                matlab.ui.control.NumericEditField
        TreinoEditFieldLabel           matlab.ui.control.Label
        ParametrosLabel                matlab.ui.control.Label
        DatasetDropDown                matlab.ui.control.DropDown
        DatasetDropDownLabel           matlab.ui.control.Label
        TreinarButton                  matlab.ui.control.Button
        OutputTextArea                 matlab.ui.control.TextArea
        OutputTextAreaLabel            matlab.ui.control.Label
        AtivacaoDropDown               matlab.ui.control.DropDown
        FunodeAtivaoSadaDropDownLabel  matlab.ui.control.Label
        TreinoDropDown                 matlab.ui.control.DropDown
        FunodeTreinoDropDownLabel      matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        NCamadasSlider                 matlab.ui.control.Slider
        NCamadasSliderLabel            matlab.ui.control.Label
        CarregarRedeTab                matlab.ui.container.Tab
        ExecutarButton                 matlab.ui.control.Button
        UITable_DescNet                matlab.ui.control.Table
        OutputTextArea_2               matlab.ui.control.TextArea
        OutputTextArea_2Label          matlab.ui.control.Label
        DatasetDropDown_2              matlab.ui.control.DropDown
        DatasetDropDown_2Label         matlab.ui.control.Label
        RedesDisponiveisListBox        matlab.ui.control.ListBox
        RedesDisponiveisListBoxLabel   matlab.ui.control.Label
        ImportarRedeButton             matlab.ui.control.Button
        CaminhodaRedeEditField         matlab.ui.control.EditField
        CaminhodaRedeEditFieldLabel    matlab.ui.control.Label
        CasoParticularTab              matlab.ui.container.Tab
        ResultadoDesconhecidoLabel     matlab.ui.control.Label
        StatusdefumadorButtonGroup     matlab.ui.container.ButtonGroup
        NuncafumouButton_2             matlab.ui.control.RadioButton
        DesconhecidoButton             matlab.ui.control.RadioButton
        FumaButton_2                   matlab.ui.control.RadioButton
        ExfumanteButton_2              matlab.ui.control.RadioButton
        PreverButton                   matlab.ui.control.Button
        ndicedeMassaCorporalEditField  matlab.ui.control.NumericEditField
        ndicedeMassaCorporalEditFieldLabel  matlab.ui.control.Label
        NveldeglicosemdioEditField     matlab.ui.control.NumericEditField
        NveldeglicosemdioEditFieldLabel  matlab.ui.control.Label
        TipodeResidenciaButtonGroup    matlab.ui.container.ButtonGroup
        UrbanoButton                   matlab.ui.control.RadioButton
        RuralButton                    matlab.ui.control.RadioButton
        DoenaCardiacaSwitch            matlab.ui.control.Switch
        DoenaCardiacaSwitchLabel       matlab.ui.control.Label
        TemHipertensoSwitch            matlab.ui.control.Switch
        TemHipertensoSwitchLabel       matlab.ui.control.Label
        JacasouSwitch                  matlab.ui.control.Switch
        JacasouSwitchLabel             matlab.ui.control.Label
        AgeSpinner                     matlab.ui.control.Spinner
        AgeSpinnerLabel                matlab.ui.control.Label
        GeneroButtonGroup              matlab.ui.container.ButtonGroup
        MulherButton                   matlab.ui.control.RadioButton
        HomemButton                    matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        gRede = [];
    end
    
    methods (Access = private)
        
        % Função para carregar a rede neural
        function loadNet(app, caminhoRede)
            % Verifica se o arquivo existe
            if exist(caminhoRede, 'file') ~= 2
                uialert(app.UIFigure, 'O arquivo não foi encontrado.', 'Erro', 'Icon', 'error');
                return;
            end
            
            % Verifica se o caminho da rede é o mesmo que já está selecionado
            if ~isempty(app.gRede) && isequal(caminhoRede, app.CaminhodaRedeEditField.Value)
                uialert(app.UIFigure, 'Esta rede já está selecionada.', 'Aviso', 'Icon', 'warning');
                return;
            end


            % Carrega a rede neural
            rede = load(caminhoRede);
            
            app.CaminhodaRedeEditField.Value = caminhoRede;

            % Obtém informações sobre a rede neural
            layers = rede.net.Layers;

            app.gRede = rede.net;


            % Mostra as informações sobre cada camada
            %disp('Informações sobre as camadas:');
            %disp(layers)
            %disp(rede)
        
            data = cell(numel(layers), 3);
            for i = 1:numel(layers)
                % Número de neurônios na camada
                num_neuronios = layers{i}.size;
        
                % Função de ativação da camada
                funcao_ativacao = layers{i}.transferFcn;
        
                %fprintf('Camada %d:\n', i);
                %fprintf('  Número de neurônios: %d\n', num_neuronios);
                %fprintf('  Função de ativação: %s\n', funcao_ativacao);
                %disp('---------------------');
        
                % Preenche os dados para a UITable_DescNet
                data{i, 1} = ['Camada ', num2str(i)];
                data{i, 2} = num_neuronios;
                data{i, 3} = funcao_ativacao;
            end
            app.UITable_DescNet.Data = data;
        end

        
        function results = useNetDataset(app)
                op = app.DatasetDropDown_2.Value;
                switch op
                    case 'Test'
                        datasetPath = 'dataset\Test.csv';
                    case 'Start'
                        datasetPath = 'dataset\Start.csv';
                    case 'Train'
                        datasetPath = 'dataset\Train_filled.csv';
                end

                % Ler dados
                data = readmatrix(datasetPath, 'Delimiter', ';', 'DecimalSeparator', '.');
                
                % Separar os inputs e targets
                input_matrix = data(:, 1:end-1);
                targets = data(:, end)';
                input_matrix = input_matrix';
                
                % Simula a rede no conjunto de dados
                out = sim(app.gRede, input_matrix);
                
                % Calcula a precisão global
                erro = perform(app.gRede, out, targets);
                globalAccuracy = (1-erro) * 100;
                
                % Extrai o nome do arquivo do caminho do modelo de rede
                [~, modelName, ~] = fileparts(app.CaminhodaRedeEditField.Value);

                % Atualiza a interface com os resultados
                newText = sprintf('O modelo %s obteve precisão: %.2f%% no dataset %s\n', modelName, globalAccuracy, op);
                app.OutputTextArea_2.Value = [newText;app.OutputTextArea_2.Value];
        end
        
        function UpdateRedesListBox(app)
            folder = 'redes';
            files = dir(fullfile(folder, '*.mat'));
            app.RedesDisponiveisListBox.Items = {};

            % Extrai apenas os nomes dos arquivos (sem extensão) e adiciona à ListBox
            for i = 1:numel(files)
                [~, name, ~] = fileparts(files(i).name);
                app.RedesDisponiveisListBox.Items{end+1} = name;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            funcoesTreino = {'trainbfg', 'trainbfgc', 'trainbr', 'trainbu', 'trainc', ...
                'traincgb', 'traincgf', 'traincgp', 'traingd', 'traingda', 'traingdm', ...
                'traingdx', 'trainlm', 'trainoss', 'trainr', 'trainrp', 'trainru', 'trains', 'trainscg'};

            app.TreinoDropDown.Items = funcoesTreino;
            app.TreinoDropDown.Value = funcoesTreino{13}; % default = trainlm

            funcoesAtivacao = {'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', ...
                   'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', ...
                   'satlins', 'softmax', 'tansig', 'tribas'};
            
            app.AtivacaoDropDown.Items = funcoesAtivacao;
            app.AtivacaoDropDown.Value = funcoesAtivacao{7}; % default = purelin

            table = cell(1, 3);
            table{1, 1} = 1; % Coluna 'Camada'
            table{1, 2} = []; % Coluna 'Nº Neurônios', vazia por padrão
            table{1, 3} = ''; % Coluna 'Função de Ativação', vazia por padrão
            app.UITable.Data = table;
            app.UITable.ColumnFormat = {'numeric', 'numeric', funcoesAtivacao};

            uialert(app.UIFigure, 'Bem Vindo! A rede é treinada 10 vezes e o output é a média dos treinos.', 'Informação de Inicialização', 'Icon', 'info');
            UpdateRedesListBox(app);
        end

        % Value changed function: NCamadasSlider
        function NCamadasSliderValueChanged(app, event)
            % Obtém o valor atual do slider e arredonda para o inteiro mais próximo
            numberOfLayers = round(app.NCamadasSlider.Value);
            app.NCamadasSlider.Value = numberOfLayers;
        
            % Inicializa um cell array vazio para os dados da tabela
            tableData = cell(numberOfLayers, 3);

            % Lista das funções de ativação disponíveis
            funcoesAtivacao = {'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', ...
                   'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', ...
                   'satlins', 'softmax', 'tansig', 'tribas'};

        
            % Preenche a primeira coluna do cell array com o número da camada
            for i = 1:numberOfLayers
                tableData{i, 1} = i; % Coluna 'Camada'
                tableData{i, 2} = []; % Coluna 'Nº Neurônios', vazia por padrão
                tableData{i, 3} = ''; % Coluna 'Função de Ativação', vazia por padrão
            end
        
            % Define os nomes das colunas da tabela
            %app.UITable.ColumnName = {'Camada', 'Nº Neurônios', 'Função de Ativação'};
            % Define as colunas como editáveis
            %app.UITable.ColumnEditable = [false, true, true]; 
        
            % Atualiza os dados na tabela
            app.UITable.Data = tableData;
        
            % Se necessário, defina os formatos de coluna após a definição dos dados
            app.UITable.ColumnFormat = {'numeric', 'numeric', funcoesAtivacao};

        end

        % Button pushed function: TreinarButton
        function TreinarButtonPushed(app, event)
            if isempty(app.UITable.Data)
                uialert(app.UIFigure, 'A tabela está vazia.', 'Erro de Entrada');
                return;
            end
            
            % verificar coluna 'N neuronios' da tabela
            linhas = size(app.UITable.Data, 1);
            for i = 1:linhas
                if isempty(app.UITable.Data{i, 2}) || isnan(app.UITable.Data{i, 2})
                    uialert(app.UIFigure, 'Por favor, insira um número válido de neurônios para todas as camadas.', 'Erro de Entrada', 'Icon', 'error');
                    return;
                end
            end
            
            % Vverificar campos da tabela de funcao de ativacao
            for i = 1:size(app.UITable.Data, 1)
                if isempty(app.UITable.Data{i, 3})
                    uialert(app.UIFigure, 'Por favor, selecione uma função de ativação para todas as camadas.', 'Erro de Entrada', 'Icon', 'error');
                    return;
                end
            end
        
            % verificar parametros (treino, validacao, teste)
            trainRatio = app.TreinoEditField.Value;
            valRatio = app.ValidacaoEditField.Value;
            testRatio = app.TesteEditField.Value;
            if isnan(trainRatio) || isnan(valRatio) || isnan(testRatio) || (trainRatio + valRatio + testRatio) ~= 1
                uialert(app.UIFigure, 'As proporções de treino, validação e teste devem somar 1.', 'Erro de Entrada', 'Icon', 'error');
                return;
            end
            
            
            treinarRede(app);

        end

        % Button pushed function: ImportarRedeButton
        function ImportarRedeButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat', 'Selecione um arquivo .mat');

            if isequal(filename,0) || isequal(filepath,0)
                disp('Nenhum arquivo selecionado');
            else
                % Concatena o caminho do arquivo com o nome do arquivo
                selectedFile = fullfile(filepath, filename);

                  redeDir = 'redes';
                  redePath = fullfile(redeDir, filename);
                    
                  if ~isfile(redePath)
                        % Se o arquivo não estiver na pasta de redes, copia para lá
                        if ~isfolder(redeDir)
                            mkdir(redeDir);
                        end
                        copyfile(selectedFile, redePath);
                        UpdateRedesListBox(app);
                  end

                loadNet(app, selectedFile);
            end

        end

        % Value changed function: RedesDisponiveisListBox
        function RedesDisponiveisListBoxValueChanged(app, event)
            % Obtém o nome do item selecionado
            nome_arquivo = app.RedesDisponiveisListBox.Value;
        
            % Adiciona a extensão .mat ao nome do arquivo
            nome_arquivo = [nome_arquivo, '.mat'];
        
            % Obtém o caminho completo do arquivo
            caminho_arquivo = fullfile(pwd, 'redes', nome_arquivo);
        
            % Chama a função para carregar a rede neural
            loadNet(app, caminho_arquivo);
            
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)

            % Verifica se o modelo de rede foi carregado
            if isempty(app.gRede)
                uialert(app.UIFigure, 'Por favor, carregue primeiro um modelo de rede.', 'Aviso', 'Icon', 'warning');
                return;
            end
            
            % Verifica se o caminho do modelo de rede foi especificado
            if isempty(app.CaminhodaRedeEditField.Value)
                uialert(app.UIFigure, 'Por favor, especifique o caminho do modelo de rede.', 'Aviso', 'Icon', 'warning');
                return;
            end

            useNetDataset(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PersonalizarRedeTab = uitab(app.TabGroup);
            app.PersonalizarRedeTab.Title = 'Personalizar Rede';
            app.NCamadasSliderLabel = uilabel(app.PersonalizarRedeTab);
            app.NCamadasSliderLabel.Text = 'Nº Camadas';
            app.NCamadasSlider = uislider(app.PersonalizarRedeTab);
            app.NCamadasSlider.Limits = [1 5];
            app.NCamadasSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NCamadasSlider.ValueChangedFcn = createCallbackFcn(app, @NCamadasSliderValueChanged, true);
            app.NCamadasSlider.MinorTicks = [];
            app.NCamadasSlider.Value = 1;
            app.UITable = uitable(app.PersonalizarRedeTab);
            app.UITable.ColumnName = {'Camada'; 'Nº Neurónios'; 'Função Ativação'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.FunodeTreinoDropDownLabel = uilabel(app.PersonalizarRedeTab);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino';
            app.TreinoDropDown = uidropdown(app.PersonalizarRedeTab);
            app.TreinoDropDown.Items = {};
            app.TreinoDropDown.Value = {};
            app.FunodeAtivaoSadaDropDownLabel = uilabel(app.PersonalizarRedeTab);
            app.FunodeAtivaoSadaDropDownLabel.Text = 'Função de Ativação (Saída)';
            app.AtivacaoDropDown = uidropdown(app.PersonalizarRedeTab);
            app.AtivacaoDropDown.Items = {};
            app.AtivacaoDropDown.Value = {};
            app.OutputTextAreaLabel = uilabel(app.PersonalizarRedeTab);
            app.OutputTextAreaLabel.Text = 'Output :';
            app.OutputTextArea = uitextarea(app.PersonalizarRedeTab);
            app.TreinarButton = uibutton(app.PersonalizarRedeTab, 'push');
            app.TreinarButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarButtonPushed, true);
            app.TreinarButton.Text = 'Treinar';
            app.DatasetDropDownLabel = uilabel(app.PersonalizarRedeTab);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.PersonalizarRedeTab);
            app.DatasetDropDown.Items = {'Test', 'Train', 'Start'};
            app.DatasetDropDown.Value = 'Test';
            app.ParametrosLabel = uilabel(app.PersonalizarRedeTab);
            app.ParametrosLabel.Text = 'Parametros:';
            app.TreinoEditFieldLabel = uilabel(app.PersonalizarRedeTab);
            app.TreinoEditFieldLabel.Text = 'Treino';
            app.TreinoEditField = uieditfield(app.PersonalizarRedeTab, 'numeric');
            app.TreinoEditField.Limits = [0 1];
            app.TreinoEditField.Value = 0.7;
            app.ValidaoEditFieldLabel = uilabel(app.PersonalizarRedeTab);
            app.ValidaoEditFieldLabel.Text = 'Validação';
            app.ValidacaoEditField = uieditfield(app.PersonalizarRedeTab, 'numeric');
            app.ValidacaoEditField.Limits = [0 1];
            app.ValidacaoEditField.Value = 0.15;
            app.TesteEditFieldLabel = uilabel(app.PersonalizarRedeTab);
            app.TesteEditFieldLabel.Text = 'Teste';
            app.TesteEditField = uieditfield(app.PersonalizarRedeTab, 'numeric');
            app.TesteEditField.Limits = [0 1];
            app.TesteEditField.Value = 0.15;
            app.CarregarRedeTab = uitab(app.TabGroup);
            app.CarregarRedeTab.Title = 'Carregar Rede';
            app.CaminhodaRedeEditFieldLabel = uilabel(app.CarregarRedeTab);
            app.CaminhodaRedeEditFieldLabel.Text = 'Caminho da Rede';
            app.CaminhodaRedeEditField = uieditfield(app.CarregarRedeTab, 'text');
            app.CaminhodaRedeEditField.Editable = 'off';
            app.ImportarRedeButton = uibutton(app.CarregarRedeTab, 'push');
            app.ImportarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarRedeButtonPushed, true);
            app.ImportarRedeButton.Text = 'Importar Rede';
            app.RedesDisponiveisListBoxLabel = uilabel(app.CarregarRedeTab);
            app.RedesDisponiveisListBoxLabel.Text = 'Redes Disponiveis';
            app.RedesDisponiveisListBox = uilistbox(app.CarregarRedeTab);
            app.RedesDisponiveisListBox.Items = {''};
            app.RedesDisponiveisListBox.ValueChangedFcn = createCallbackFcn(app, @RedesDisponiveisListBoxValueChanged, true);
            app.RedesDisponiveisListBox.Value = '';
            app.DatasetDropDown_2Label = uilabel(app.CarregarRedeTab);
            app.DatasetDropDown_2Label.Text = 'Dataset';
            app.DatasetDropDown_2 = uidropdown(app.CarregarRedeTab);
            app.DatasetDropDown_2.Items = {'Test', 'Train', 'Start'};
            app.DatasetDropDown_2.Value = 'Test';
            app.OutputTextArea_2Label = uilabel(app.CarregarRedeTab);
            app.OutputTextArea_2Label.Text = 'Output :';
            app.OutputTextArea_2 = uitextarea(app.CarregarRedeTab);
            app.OutputTextArea_2.Editable = 'off';
            app.UITable_DescNet = uitable(app.CarregarRedeTab);
            app.UITable_DescNet.ColumnName = {'Camada'; 'Nº Neurónios'; 'Função Ativação'};
            app.UITable_DescNet.RowName = {};
            app.UITable_DescNet.ColumnEditable = [false true true];
            app.UITable_DescNet.Enable = 'off';
            app.ExecutarButton = uibutton(app.CarregarRedeTab, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.CasoParticularTab = uitab(app.TabGroup);
            app.CasoParticularTab.Title = 'Caso Particular';
            app.GeneroButtonGroup = uibuttongroup(app.CasoParticularTab);
            app.GeneroButtonGroup.Title = 'Genero';
            app.HomemButton = uiradiobutton(app.GeneroButtonGroup);
            app.HomemButton.Text = 'Homem';
            app.HomemButton.Value = true;
            app.MulherButton = uiradiobutton(app.GeneroButtonGroup);
            app.MulherButton.Text = 'Mulher';
            app.AgeSpinnerLabel = uilabel(app.CasoParticularTab);
            app.AgeSpinnerLabel.Text = 'Age';
            app.AgeSpinner = uispinner(app.CasoParticularTab);
            app.AgeSpinner.Value = 1;
            app.JacasouSwitchLabel = uilabel(app.CasoParticularTab);
            app.JacasouSwitchLabel.Text = 'Ja casou?';
            app.JacasouSwitch = uiswitch(app.CasoParticularTab, 'slider');
            app.JacasouSwitch.Items = {'Não', 'Sim'};
            app.JacasouSwitch.Value = 'Sim';
            app.TemHipertensoSwitchLabel = uilabel(app.CasoParticularTab);
            app.TemHipertensoSwitchLabel.Text = 'Tem Hipertensão';
            app.TemHipertensoSwitch = uiswitch(app.CasoParticularTab, 'slider');
            app.TemHipertensoSwitch.Items = {'Não', 'Sim'};
            app.TemHipertensoSwitch.Value = 'Não';
            app.DoenaCardiacaSwitchLabel = uilabel(app.CasoParticularTab);
            app.DoenaCardiacaSwitchLabel.Text = 'Doença Cardiaca?';
            app.DoenaCardiacaSwitch = uiswitch(app.CasoParticularTab, 'slider');
            app.DoenaCardiacaSwitch.Items = {'Não', 'Sim'};
            app.DoenaCardiacaSwitch.Value = 'Sim';
            app.TipodeResidenciaButtonGroup = uibuttongroup(app.CasoParticularTab);
            app.TipodeResidenciaButtonGroup.Title = 'Tipo de Residencia';
            app.RuralButton = uiradiobutton(app.TipodeResidenciaButtonGroup);
            app.RuralButton.Text = 'Rural';
            app.RuralButton.Value = true;
            app.UrbanoButton = uiradiobutton(app.TipodeResidenciaButtonGroup);
            app.UrbanoButton.Text = 'Urbano';
            app.NveldeglicosemdioEditFieldLabel = uilabel(app.CasoParticularTab);
            app.NveldeglicosemdioEditFieldLabel.Text = 'Nível de glicose médio';
            app.NveldeglicosemdioEditField = uieditfield(app.CasoParticularTab, 'numeric');
            app.ndicedeMassaCorporalEditFieldLabel = uilabel(app.CasoParticularTab);
            app.ndicedeMassaCorporalEditFieldLabel.Text = 'Índice de Massa Corporal';
            app.ndicedeMassaCorporalEditField = uieditfield(app.CasoParticularTab, 'numeric');
            app.PreverButton = uibutton(app.CasoParticularTab, 'push');
            app.PreverButton.Text = 'Prever';
            app.StatusdefumadorButtonGroup = uibuttongroup(app.CasoParticularTab);
            app.StatusdefumadorButtonGroup.Title = 'Status de fumador';
            app.ExfumanteButton_2 = uiradiobutton(app.StatusdefumadorButtonGroup);
            app.ExfumanteButton_2.Text = {'Ex-fumante'; ''};
            app.ExfumanteButton_2.Value = true;
            app.FumaButton_2 = uiradiobutton(app.StatusdefumadorButtonGroup);
            app.FumaButton_2.Text = 'Fuma';
            app.DesconhecidoButton = uiradiobutton(app.StatusdefumadorButtonGroup);
            app.DesconhecidoButton.Text = 'Desconhecido';
            app.NuncafumouButton_2 = uiradiobutton(app.StatusdefumadorButtonGroup);
            app.NuncafumouButton_2.Text = {'Nunca fumou'; ''};
            app.ResultadoDesconhecidoLabel = uilabel(app.CasoParticularTab);
            app.ResultadoDesconhecidoLabel.Text = '  Resultado : Desconhecido';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end